classdef speaker_recog_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RecordButton        matlab.ui.control.Button
        PlayoriginalButton  matlab.ui.control.Button
        SecsSpinnerLabel    matlab.ui.control.Label
        SecsSpinner         matlab.ui.control.Spinner
        UIAudioSample       matlab.ui.control.UIAxes
        UIMel               matlab.ui.control.UIAxes
        SpeakerLabel        matlab.ui.control.Label
        KnownSpeaker        matlab.ui.control.Label
        SelectfileButton    matlab.ui.control.StateButton
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        recObj = audiorecorder(16000, 24, 1); % Creando el objeto de audio
        fs;                     % Extrayendo la frecuencia de muestreo del objeto de grabación.
        n; 
        t; 
        
        %betas; 
        classification;
        prob;
        probNew;
        clasiNew;
        probisNew;
        
        datosGrabacion;         % Objeto que guarda los datos de la grabacion original.
        file;
        path;
    end
    
    methods (Access = private)
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % funcion de acualización de gráficas
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function [] = updateGraphs(app)
            %%%graficar la señal de audio
            plot(app.UIAudioSample, app.t, app.datosGrabacion);
            xlim(app.UIAudioSample, [0 app.SecsSpinner.Value]);
            
            
            %normalizamos la señal
            signal = app.datosGrabacion ./ max(app.datosGrabacion);
            
            %filtramos el audio para aceptar solo freqs entre 20Hz y 4kHz
            cutoff_freqs = [20/(app.fs/2), 4000/(app.fs/2)];
            [b, a] = butter(4, cutoff_freqs);
            filtered_signal = filter(b, a, signal);
            
            %calcula los coefs de la mfcc
            coefs = mfcc(filtered_signal, app.fs, 'NumCoeffs', 38);
            
            imagesc(app.UIMel,coefs);
            c = colorbar(app.UIMel);
            colormap(app.UIMel, winter)
            c.Label.String = 'Intensity';
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % funcion de clasificación
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [] = classify(app)
            %app.betas = regresion_logistica_train();
            [app.prob, app.classification] = regresion_logistica_test( app.datosGrabacion, app.fs);
            if app.classification == 1 
                app.KnownSpeaker.Text = '¡Dave!';
                app.KnownSpeaker.FontColor = [0.07,0.62,1.00];
                
            elseif app.classification == 2   
                app.KnownSpeaker.Text = '¡Dayana!'; 
                app.KnownSpeaker.FontColor= [0.72,0.27,1.00];
                
            else
                app.KnownSpeaker.Text = "I don't know u :c";
                app.KnownSpeaker.FontColor= [1.00,0.00,0.00];
            end
            
            
            app.KnownSpeaker.Visible = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.fs = app.recObj.SampleRate; % Extrayendo la frecuencia de muestreo del objeto de grabación.
            
            recordblocking(app.recObj, app.SecsSpinner.Value);
            
            % extraer el audio
            app.datosGrabacion = getaudiodata(app.recObj);
            app.datosGrabacion = app.datosGrabacion ./ max(app.datosGrabacion);
            
            % probar el audio
            sound(app.datosGrabacion,app.fs);
            
            % procesar la información
            app.n = length(app.datosGrabacion);
            app.t = (0:app.n-1)/app.fs;
            
                
            updateGraphs(app);
            classify(app);


        end

        % Value changed function: SelectfileButton
        function SelectfileButtonValueChanged(app, event)
            value = app.SelectfileButton.Value;
            [app.file, app.path] = uigetfile('*.wav');
            
            %lee la muestra de audio
            [app.datosGrabacion, app.fs]  = audioread( fullfile(app.path, app.file) );   
            
            T = 1/app.fs;                           %periodo de la señal   
            L = length(app.datosGrabacion);         %largo de la señal
            app.t = (0:L-1)*T;                      %tiempo

            % probar el audio
            sound(app.datosGrabacion, app.fs);
            
            updateGraphs(app);
            classify(app);

        end

        % Button pushed function: PlayoriginalButton
        function PlayoriginalButtonPushed(app, event)
            % probar el audio
            sound(app.datosGrabacion, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayoriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalButtonPushed, true);
            app.PlayoriginalButton.Text = 'Play  original';
            app.SecsSpinnerLabel = uilabel(app.UIFigure);
            app.SecsSpinnerLabel.Text = 'Secs';
            app.SecsSpinner = uispinner(app.UIFigure);
            app.SecsSpinner.Limits = [1 Inf];
            app.SecsSpinner.Value = 2;
            app.UIAudioSample = uiaxes(app.UIFigure);
            title(app.UIAudioSample, 'Audio sample')
            xlabel(app.UIAudioSample, 'time (secs)')
            ylabel(app.UIAudioSample, 'Air pressure')
            app.UIMel = uiaxes(app.UIFigure);
            title(app.UIMel, 'Mel-cepstrum coeffs')
            xlabel(app.UIMel, 'Coeff number')
            ylabel(app.UIMel, 'Window ')
            app.SpeakerLabel = uilabel(app.UIFigure);
            app.SpeakerLabel.Text = 'Speaker : ';
            app.KnownSpeaker = uilabel(app.UIFigure);
            app.KnownSpeaker.Visible = 'off';
            app.SelectfileButton = uibutton(app.UIFigure, 'state');
            app.SelectfileButton.ValueChangedFcn = createCallbackFcn(app, @SelectfileButtonValueChanged, true);
            app.SelectfileButton.Text = 'Select file';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speaker_recog_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end