classdef speaker_recog_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RecordButton        matlab.ui.control.Button
        PlayoriginalButton  matlab.ui.control.Button
        SecsSpinnerLabel    matlab.ui.control.Label
        SecsSpinner         matlab.ui.control.Spinner
        UIAudioSample       matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        SpeakerLabel        matlab.ui.control.Label
        KnownSpeaker        matlab.ui.control.Label
    end

    
    properties (Access = public)
        recObj = audiorecorder; % Creando el objeto de audio
        fs;                     % Extrayendo la frecuencia de muestreo del objeto de grabación.
        n; 
        t; 
        
        datosGrabacion;         % Objeto que guarda los datos de la grabacion original.
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.fs = app.recObj.SampleRate; % Extrayendo la frecuencia de muestreo del objeto de grabación.
            
            recordblocking(app.recObj, app.SecsSpinner.Value);
            
             % extraer el audio
            app.datosGrabacion = getaudiodata(app.recObj);
            app.datosGrabacion = app.datosGrabacion ./ max(app.datosGrabacion);
            
            % probar el audio
            sound(app.datosGrabacion,app.fs);
            
            % procesar la información
            app.n = length(app.datosGrabacion);
            app.t = (0:app.n-1)/app.fs;
            
            %graficar la señal de audio
            plot(app.UIAudioSample, app.t, app.datosGrabacion);
            xlim(app.UIAudioSample, [0 app.SecsSpinner.Value]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayoriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayoriginalButton.Text = 'Play  original';
            app.SecsSpinnerLabel = uilabel(app.UIFigure);
            app.SecsSpinnerLabel.Text = 'Secs';
            app.SecsSpinner = uispinner(app.UIFigure);
            app.SecsSpinner.Limits = [0.1 Inf];
            app.SecsSpinner.Value = 0.1;
            app.UIAudioSample = uiaxes(app.UIFigure);
            title(app.UIAudioSample, 'Audio sample')
            xlabel(app.UIAudioSample, 'time (secs)')
            ylabel(app.UIAudioSample, 'Air pressure')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mel-cepstrum-coefs')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SpeakerLabel = uilabel(app.UIFigure);
            app.SpeakerLabel.Text = 'Speaker : ';
            app.KnownSpeaker = uilabel(app.UIFigure);
            app.KnownSpeaker.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speaker_recog_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end